classdef Music < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MusicUIFigure    matlab.ui.Figure
        Button           matlab.ui.control.Button
        Button2          matlab.ui.control.Button
        Button3          matlab.ui.control.Button
        EditFieldLabel   matlab.ui.control.Label
        EditField        matlab.ui.control.EditField
        UIAxes           matlab.ui.control.UIAxes
        EditField4Label  matlab.ui.control.Label
        EditField2       matlab.ui.control.EditField
        EditField5Label  matlab.ui.control.Label
        EditField3       matlab.ui.control.EditField
        Label            matlab.ui.control.Label
        Label2           matlab.ui.control.Label
        UIAxes2          matlab.ui.control.UIAxes
    end


    properties (Access = private)
            % 定义私有属性
            
            % https://xiaoapi.cn/API/yy_sq.php?msg=稻香&type=json&n=1
            httpBase = "https://xiaoapi.cn/API/yy_sq.php?msg=";
            msg = "梦想家童声版";
            tailHttp ="&type=json&n=1";
            Mdata=' ';
            
    end
    
    methods (Static)
        function Hd = btw(~)
        %BTW 返回离散时间滤波器对象。
        
        % MATLAB Code
        % Generated by MATLAB(R) 9.8 and Signal Processing Toolbox 8.4.
        % Generated on: 29-Aug-2021 01:48:30
        
        % Butterworth Highpass filter designed using FDESIGN.HIGHPASS.
        
        % All frequency values are in Hz.
        Fs = 44100;  % Sampling Frequency
        
        Fstop = 1000;        % Stopband Frequency
        Fpass = 2000;        % Passband Frequency
        Astop = 80;          % Stopband Attenuation (dB)
        Apass = 1;           % Passband Ripple (dB)
        match = 'stopband';  % Band to match exactly
        
        % Construct an FDESIGN object and call its BUTTER method.
        h  = fdesign.highpass(Fstop, Fpass, Astop, Apass, Fs);
        Hd = design(h, 'butter', 'MatchExactly', match);
        
        % [EOF]
        
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
          
            % 格式化控件格式
            app.Label2.HorizontalAlignment='left';
            app.Label2.Text=string(date);


            app.EditFieldLabel.BackgroundColor='g';
            
            app.EditFieldLabel.Text="搜索";
            
            app.EditFieldLabel.HorizontalAlignment='center';
            
            app.EditField.HorizontalAlignment='left';
              
            app.EditField4Label.BackgroundColor='cyan';
            app.EditField4Label.HorizontalAlignment='center';
            app.EditField4Label.Text="歌名";
            app.EditField2.Enable =false;
            
            app.EditField2.Value="NULL";


            app.EditField5Label.BackgroundColor='magenta';
            app.EditField5Label.HorizontalAlignment='center';
            app.EditField5Label.Text="歌手";
            app.EditField3.Enable =false;
            app.EditField3.Value="NULL";
            
            
            app.Button.Text="Play";
            app.Button2.Text="Stop";
            app.Button3.Text="Search";
            
            app.Label.HorizontalAlignment='left';


            app.UIAxes.Title.String= " ";
            app.UIAxes.XLabel.String = " ";
            
            app.UIAxes.YLabel.String = " ";


            %% 运行环境
            
            [version, ~, isloaded] = pyversion;

            if(isloaded)
                
                
                import py.requests.Request


                disp(strcat("你运行的环境是python环境是: ",version));
                % py.importlib.import_module('requests')
                
             else
                disp("Pls check the Python Env")
            end

             %  初始化业务数据
             app.EditField.Value=app.msg;
              
              validHttp =strcat( app.httpBase,app.msg, app.tailHttp);
             Response = py.requests.get(validHttp);

             app.Mdata  =  jsondecode(string(Response.text));


             if 200 == app.Mdata.code
               
                 app.EditField2.Value = app.Mdata.name;
                 
                  app.EditField3.Value =app.Mdata.singer;
                  returnImage = webread(app.Mdata.cover,weboptions('ContentType','image'));
                  imshow(returnImage,'parent',app.UIAxes)
                 app.Label.Text="API OK";
                 app.Label.BackgroundColor='g';
             else
                 disp("API is not Valid Or check your network");
                  app.Label.BackgroundColor='r';
             end
              
            
        end

        % Button pushed function: Button3
        function Button3Pushed(app, event)
            % 业务逻辑实现层 搜索数据 ，实现数据加载
            
           app.msg = app.EditField.Value;


           validHttp =strcat( app.httpBase,app.msg, app.tailHttp);
           
           
            disp(validHttp);
           
            Response = py.requests.get(validHttp);
            
            app.Mdata  =  jsondecode(string(Response.text));
            
            app.EditField2.Value = app.Mdata.name;
            
            app.EditField3.Value = app.Mdata.singer;
            
            
            returnImage = webread(app.Mdata.cover,weboptions('ContentType','image'));
            imshow(returnImage,'parent',app.UIAxes)

            disp( app.Mdata)


        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 播放对应的歌曲   
            
             [returnAudio,returnFs] = webread(app.Mdata.url,weboptions('ContentType','audio','Timeout',60));
             
             % sound(returnAudio,returnFs)
             
             
             %% 绘制频谱图   https://www.52pojie.cn/thread-1502109-1-1.html
             
             div = 0.2;
            y = returnAudio;
            
            Fs = returnFs;
            
            L = length(y)-mod(length(y),2);
 
            l = div*Fs;
            
            %% HPF
            app.btw
            Hlp = app.btw();
            y1 = filtfilt(Hlp.sosMatrix, 1, y);
            
            %% play music
            sound(y,Fs);
            
            %% FFT
            for i=1:L/(l-1)
                t1 = clock;
                x = y1(i*l:(i+1)*l);
                FFT(x,l,Fs);
                t2 = etime(clock,t1);
                pause(div-t2);
            end
            
            function [] = FFT(x,l,Fs)
            Y = fft(x);
            P2 = abs(Y/l);
            P1 = P2(1:l/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = Fs*(0:(l/2))/l;
            
            
%             app.UIAxes2.XTick =f;
%             
%             app.UIAxes2.YTick = Y;
            
          
            plot(app.UIAxes2,f,P1);
            %axis([1e3 1.8e4 0 0.15]);
            end


        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            disp("Music Stop");
            clear sound;
            
            % clf(app.UIAxes2);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MusicUIFigure = uifigure;
            app.MusicUIFigure.Name = 'Music';
            app.Button = uibutton(app.MusicUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button2 = uibutton(app.MusicUIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            app.Button3 = uibutton(app.MusicUIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button3.Text = 'Button3';
            app.EditFieldLabel = uilabel(app.MusicUIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.MusicUIFigure, 'text');
            app.UIAxes = uiaxes(app.MusicUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.EditField4Label = uilabel(app.MusicUIFigure);
            app.EditField4Label.Text = 'Edit Field4';
            app.EditField2 = uieditfield(app.MusicUIFigure, 'text');
            app.EditField5Label = uilabel(app.MusicUIFigure);
            app.EditField5Label.Text = 'Edit Field5';
            app.EditField3 = uieditfield(app.MusicUIFigure, 'text');
            app.Label = uilabel(app.MusicUIFigure);
            app.Label2 = uilabel(app.MusicUIFigure);
            app.Label2.Text = 'Label2';
            app.UIAxes2 = uiaxes(app.MusicUIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = Music

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MusicUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MusicUIFigure)
        end
    end
end